classdef demoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        AnalysisPanel                matlab.ui.container.Panel
        GeneticPrototypingButton     matlab.ui.control.Button
        DomainConfigurationPanel     matlab.ui.container.Panel
        domainDropDownLabel          matlab.ui.control.Label
        domainDropDown               matlab.ui.control.DropDown
        featurexaxisDropDownLabel    matlab.ui.control.Label
        featurexaxisDropDown         matlab.ui.control.DropDown
        featureyaxisDropDownLabel    matlab.ui.control.Label
        featureyaxisDropDown         matlab.ui.control.DropDown
        loadeddomainLabel            matlab.ui.control.Label
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes5_2                    matlab.ui.control.UIAxes
        QDConfigurationPanel         matlab.ui.container.Panel
        ArchiveDropDownLabel         matlab.ui.control.Label
        ArchiveDropDown              matlab.ui.control.DropDown
        nogenerationsEditFieldLabel  matlab.ui.control.Label
        nogenerationsEditField       matlab.ui.control.NumericEditField
        QDcommandsPanel              matlab.ui.container.Panel
        InitRestartButton            matlab.ui.control.Button
        RedrawButton                 matlab.ui.control.Button
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes6_2                    matlab.ui.control.UIAxes
        UserSelectionPanel           matlab.ui.container.Panel
        AddSelectionButton           matlab.ui.control.Button
        RemoveButton                 matlab.ui.control.Button
        ShowUDHMButton               matlab.ui.control.Button
        UserSelectionPressureSliderLabel  matlab.ui.control.Label
        UserSelectionPressureSlider  matlab.ui.control.Slider
        IterationListBoxLabel        matlab.ui.control.Label
        IterationListBox             matlab.ui.control.ListBox
        TabGroup                     matlab.ui.container.TabGroup
        DomainTab                    matlab.ui.container.Tab
        IlluminationTab              matlab.ui.container.Tab
        SelectionTab                 matlab.ui.container.Tab
    end

    
    properties (Access = public)
        p % Algorithm configuration
        d % Domain configuration
        map % Current map object
        classification % Classification
        
        % Workaround: get ID of interactive axes in UIFigure
        mapChildID 
        prototypeChildID
        selectionChildID
        
        selectedPrototypes % Keep selection saved here
    end
    
    methods (Access = private)
        
        function results = setDomain(app, DOMAIN)
            % Load domain
            rmpath(genpath('domain')); addpath(['domain/' DOMAIN]);
            app.d = domain;
            app.loadeddomainLabel.Text = ['DOMAIN: ' DOMAIN];
            % Set features to GUI
            app.featurexaxisDropDown.Items = app.d.featureLabels;
            app.featureyaxisDropDown.Items = app.d.featureLabels;  
            app.featurexaxisDropDown.Value = app.featurexaxisDropDown.Items{1};            
            app.featureyaxisDropDown.Value = app.featureyaxisDropDown.Items{2};            
            % Load example
            showExample(app.UIAxes3,app.d);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
            DOMAIN = app.domainDropDown.Value; %npoly dropwave shubert4
            setDomain(app, DOMAIN);
            ALGORITHM = 'voronoi';
            rmpath('grid'); rmpath('voronoi'); addpath(ALGORITHM);
            % Load configuration 
            app.p = defaultParamSet(4);
            app.nogenerationsEditField.Value = app.p.nGens;
            
            app.UIAxes.Title.String = 'Phenotypic Map';
            app.UIAxes2.Title.String = 'Genotypic Similarity';
            app.UIAxes.Tag = 'map';
            app.UIAxes5.Tag = 'prototypes';
            app.UIAxes5_2.Tag = 'selection';
            
            % Workaround: get ID of map figure in UIFigure
            for i=1:length(app.UIFigure.Children)                
                if strcmp(app.UIFigure.Children(i).Tag,'map')
                    app.mapChildID = i;
                end
                if strcmp(app.UIFigure.Children(i).Tag,'prototypes')
                    app.prototypeChildID = i;
                end
                if strcmp(app.UIFigure.Children(i).Tag,'selection')
                    app.selectionChildID = i;
                end
            end
            
        end

        % Button pushed function: InitRestartButton
        function InitRestartButtonPushed(app, event)
            
            sobSequence         = scramble(sobolset(app.d.dof,'Skip',1e3),'MatousekAffineOwen');
            sobPoint            = 1;
            initSamples         = range(app.d.ranges).*sobSequence(sobPoint:(sobPoint+app.p.numInitSamples)-1,:)+app.d.ranges(1);
            [fitness, values]   = app.d.fitfun(initSamples);
            obsMap              = createMap(app.d.dof, app.p);
            [replaced, replacement, features] = nicheCompete(initSamples, fitness, values, obsMap, app.d);
            obsMap = updateMap(replaced,replacement,obsMap,fitness,initSamples,features);
            
            [app.map, ~, ~, ~, allMaps] = illuminate(app.d.fitfun,obsMap,app.p,app.d);
            
            % Show data            
            app.UIAxes.cla;
            viewMap(app.map,app.d,app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);            
        end

        % Value changed function: nogenerationsEditField
        function nogenerationsEditFieldValueChanged(app, event)
            value = app.nogenerationsEditField.Value;
            app.p.nGens = value;
            app.nogenerationsEditField.Value = value;
        end

        % Value changed function: domainDropDown
        function domainDropDownValueChanged(app, event)
            value = app.domainDropDown.Value;
            setDomain(app, value);
        end

        % Value changed function: featurexaxisDropDown
        function featurexaxisDropDownValueChanged(app, event)
            value = app.featurexaxisDropDown.Value;
            col = find(strcmp(value,app.d.featureLabels));
            app.d.selectedFeatures(1) = col;
        end

        % Value changed function: featureyaxisDropDown
        function featureyaxisDropDownValueChanged(app, event)
            value = app.featureyaxisDropDown.Value;
            col = find(strcmp(value,app.d.featureLabels));
            app.d.selectedFeatures(2) = col;            
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
            app.UIAxes.cla;
            viewMap(app.map,app.d,app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);
        end

        % Button pushed function: GeneticPrototypingButton
        function GeneticPrototypingButtonPushed(app, event)
            app.classification = extractClasses(app.map.genes);
            % Show classes in similarity space
            viewClasses(app.map.genes,app.classification,app.UIAxes2);
            % Show prototypes
            % Row and col placement
            numRows = ceil( size(app.classification.protoX,1).^(2/3) );
            for i=1:size(app.classification.protoX,1)
                placement(i,:) = [mod(i-1,numRows) floor((i-1)/numRows)]*app.d.spacer;
            end
            showExample(app.UIAxes5,app.d,app.classification.protoX,placement);
            axis(app.UIAxes5, 'equal');       
        end

        % Value changed function: ArchiveDropDown
        function ArchiveDropDownValueChanged(app, event)
            value = app.ArchiveDropDown.Value;
            ALGORITHM = value;
            rmpath('grid'); rmpath('voronoi'); addpath(ALGORITHM);       
            nGens = app.p.nGens;
            app.p = defaultParamSet(4);
            app.p.nGens = nGens;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %% Workarounds for UIAxes not having callbacks..
            %  Click on map and show solution
            pos = event.Source.CurrentPoint;
            
            figPos = event.Source.Children(app.mapChildID).InnerPosition;
            if ~isempty(app.map)
            if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                    % Get position of mouse click relative to inner position (axis, not figure!)
                    relPos = [(pos(1)-figPos(1))./(figPos(3)) (pos(2)-figPos(2))./(figPos(4))];
                    distances = pdist2(relPos,app.map.features(:,app.d.selectedFeatures));
                    [~,closest] = min(distances);
                    showExample(app.UIAxes3_2,app.d,app.map.genes(closest,:));
                end
            end
            end
            
            figPos = event.Source.Children(app.prototypeChildID).InnerPosition;
            if ~isempty(app.classification)
            if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                    relPos = event.Source.Children(app.prototypeChildID).CurrentPoint(1,1:2);
                    polyAxes = event.Source.Children(app.prototypeChildID).Children;
                    polys = []; numVerts = 0;
                    for i=1:length(polyAxes)
                        polys = [polys;polyAxes(i).Shape.Vertices];
                        numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                    end
                    numVerts(1) = [];
                    distances = pdist2(relPos,polys);
                    [~,closest] = min(distances);
                    closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                    closestPrototypes = find(closestPrototypes);
                    app.selectedPrototypes(end+1) = closestPrototypes(end);
                    % Row and col placement
                    for i=1:size(app.classification.protoX(app.selectedPrototypes,:),1)
                        placement(i,:) = [(i-1)*app.d.spacer 0];
                    end
                    showExample(app.UIAxes5_2,app.d,app.classification.protoX(app.selectedPrototypes,:),placement);
                end
            end
            end
            
            figPos = event.Source.Children(app.selectionChildID).InnerPosition;
            if ~isempty(app.selectedPrototypes)
            if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                    relPos = event.Source.Children(app.selectionChildID).CurrentPoint(1,1:2);
                    polyAxes = event.Source.Children(app.selectionChildID).Children;
                    polys = []; numVerts = 0;
                    for i=1:length(polyAxes)
                        polys = [polys;polyAxes(i).Shape.Vertices];
                        numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                    end
                    numVerts(1) = [];
                    distances = pdist2(relPos,polys);
                    [~,closest] = min(distances);
                    closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                    closestPrototypes = find(closestPrototypes);
                    app.selectedPrototypes(closestPrototypes(end)) = [];
                    % Row and col placement
                    if ~isempty(app.selectedPrototypes)
                        for i=1:size(app.classification.protoX(app.selectedPrototypes,:),1)
                            placement(i,:) = [(i-1)*app.d.spacer 0];
                        end
                        showExample(app.UIAxes5_2,app.d,app.classification.protoX(app.selectedPrototypes,:),placement);
                    else
                        cla(app.UIAxes5_2);
                    end
                end
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Phenotypic Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Genotypic Space')
            xlabel(app.UIAxes2, '1')
            ylabel(app.UIAxes2, '2')
            app.UIAxes2.XTick = [0 0.5 1];
            app.UIAxes2.YTick = [0 0.5 1];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Example Solution')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.GeneticPrototypingButton = uibutton(app.AnalysisPanel, 'push');
            app.GeneticPrototypingButton.ButtonPushedFcn = createCallbackFcn(app, @GeneticPrototypingButtonPushed, true);
            app.GeneticPrototypingButton.Text = 'Genetic Prototyping';
            app.DomainConfigurationPanel = uipanel(app.UIFigure);
            app.DomainConfigurationPanel.Title = 'Domain Configuration';
            app.domainDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.domainDropDownLabel.Text = 'domain';
            app.domainDropDown = uidropdown(app.DomainConfigurationPanel);
            app.domainDropDown.Items = {'dropwave', 'shubert4', 'npoly'};
            app.domainDropDown.ValueChangedFcn = createCallbackFcn(app, @domainDropDownValueChanged, true);
            app.domainDropDown.Value = 'npoly';
            app.featurexaxisDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.featurexaxisDropDownLabel.Text = 'feature x axis';
            app.featurexaxisDropDown = uidropdown(app.DomainConfigurationPanel);
            app.featurexaxisDropDown.Items = {'default'};
            app.featurexaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featurexaxisDropDownValueChanged, true);
            app.featurexaxisDropDown.Value = 'default';
            app.featureyaxisDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.featureyaxisDropDownLabel.Text = 'feature y axis';
            app.featureyaxisDropDown = uidropdown(app.DomainConfigurationPanel);
            app.featureyaxisDropDown.Items = {'default'};
            app.featureyaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featureyaxisDropDownValueChanged, true);
            app.featureyaxisDropDown.Value = 'default';
            app.loadeddomainLabel = uilabel(app.DomainConfigurationPanel);
            app.loadeddomainLabel.Text = 'loaded domain: ';
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Prototypes')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes5.XLim = [0 2.5];
            app.UIAxes5_2 = uiaxes(app.UIFigure);
            title(app.UIAxes5_2, 'Selection')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            app.UIAxes5_2.XLim = [-1 20];
            app.UIAxes5_2.YLim = [-2 2];
            app.QDConfigurationPanel = uipanel(app.UIFigure);
            app.QDConfigurationPanel.Title = 'QD Configuration';
            app.ArchiveDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.ArchiveDropDownLabel.Text = 'Archive';
            app.ArchiveDropDown = uidropdown(app.QDConfigurationPanel);
            app.ArchiveDropDown.Items = {'voronoi', 'grid', ''};
            app.ArchiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ArchiveDropDownValueChanged, true);
            app.ArchiveDropDown.Value = 'voronoi';
            app.nogenerationsEditFieldLabel = uilabel(app.QDConfigurationPanel);
            app.nogenerationsEditFieldLabel.Text = 'no. generations';
            app.nogenerationsEditField = uieditfield(app.QDConfigurationPanel, 'numeric');
            app.nogenerationsEditField.Limits = [1 10000];
            app.nogenerationsEditField.RoundFractionalValues = 'on';
            app.nogenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @nogenerationsEditFieldValueChanged, true);
            app.nogenerationsEditField.Value = 100;
            app.QDcommandsPanel = uipanel(app.UIFigure);
            app.QDcommandsPanel.Title = 'QD commands';
            app.InitRestartButton = uibutton(app.QDcommandsPanel, 'push');
            app.InitRestartButton.ButtonPushedFcn = createCallbackFcn(app, @InitRestartButtonPushed, true);
            app.InitRestartButton.Text = 'Init/Restart';
            app.RedrawButton = uibutton(app.QDcommandsPanel, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Text = 'Redraw';
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Viewed Solution')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes6_2 = uiaxes(app.UIFigure);
            title(app.UIAxes6_2, 'Selection Model (G)')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            app.UserSelectionPanel = uipanel(app.UIFigure);
            app.UserSelectionPanel.Title = 'User Selection';
            app.AddSelectionButton = uibutton(app.UserSelectionPanel, 'push');
            app.AddSelectionButton.Text = 'Add Selection';
            app.RemoveButton = uibutton(app.UserSelectionPanel, 'push');
            app.RemoveButton.Text = 'Remove';
            app.ShowUDHMButton = uibutton(app.UserSelectionPanel, 'push');
            app.ShowUDHMButton.Text = 'Show UDHM';
            app.UserSelectionPressureSliderLabel = uilabel(app.UserSelectionPanel);
            app.UserSelectionPressureSliderLabel.Text = {'User'; ' Selection'; ' Pressure'};
            app.UserSelectionPressureSlider = uislider(app.UserSelectionPanel);
            app.UserSelectionPressureSlider.MajorTicks = [0 25 50 75 100];
            app.UserSelectionPressureSlider.MajorTickLabels = {'0%', '25%', '50%', '75%', '100%'};
            app.IterationListBoxLabel = uilabel(app.UIFigure);
            app.IterationListBoxLabel.Text = 'Iteration';
            app.IterationListBox = uilistbox(app.UIFigure);
            app.IterationListBox.Items = {'1'};
            app.IterationListBox.Value = '1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DomainTab = uitab(app.TabGroup);
            app.DomainTab.Title = 'Domain';
            app.IlluminationTab = uitab(app.TabGroup);
            app.IlluminationTab.Title = 'Illumination';
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end