classdef demoGUItabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DomainTab                       matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        DomainConfigurationPanel        matlab.ui.container.Panel
        domainDropDownLabel             matlab.ui.control.Label
        domainDropDown                  matlab.ui.control.DropDown
        loadeddomainLabel               matlab.ui.control.Label
        DOFDropDownLabel                matlab.ui.control.Label
        DOFDropDown                     matlab.ui.control.DropDown
        DescriptionTextAreaLabel        matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        IlluminationTab                 matlab.ui.container.Tab
        QDConfigurationPanel            matlab.ui.container.Panel
        archiveDropDownLabel            matlab.ui.control.Label
        archiveDropDown                 matlab.ui.control.DropDown
        nogenerationsEditFieldLabel     matlab.ui.control.Label
        nogenerationsEditField          matlab.ui.control.NumericEditField
        featurexaxisDropDownLabel       matlab.ui.control.Label
        featurexaxisDropDown            matlab.ui.control.DropDown
        featureyaxisDropDownLabel       matlab.ui.control.Label
        featureyaxisDropDown            matlab.ui.control.DropDown
        surrogateassistanceCheckBox     matlab.ui.control.CheckBox
        resolutionSliderLabel           matlab.ui.control.Label
        resolutionSlider                matlab.ui.control.Slider
        QDcommandsPanel                 matlab.ui.container.Panel
        InitRestartButton               matlab.ui.control.Button
        RedrawButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes11_2                      matlab.ui.control.UIAxes
        VisualizeDriftCheckBox          matlab.ui.control.CheckBox
        UIAxes12                        matlab.ui.control.UIAxes
        UserSelectionPressurePanel      matlab.ui.container.Panel
        SliderLabel                     matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        ExplicitTab                     matlab.ui.container.Tab
        UIAxes13                        matlab.ui.control.UIAxes
        AnalysisSelectionTab            matlab.ui.container.Tab
        AnalysisPanel                   matlab.ui.container.Panel
        GeneticPrototypingButton        matlab.ui.control.Button
        noprototypesSliderLabel         matlab.ui.control.Label
        noprototypesSlider              matlab.ui.control.Slider
        SimilaritySpaceButton           matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UserSelectionPanel              matlab.ui.container.Panel
        AddSelectionButton              matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        consoleTextAreaLabel            matlab.ui.control.Label
        consoleTextArea                 matlab.ui.control.TextArea
        SelectionIterationListBoxLabel  matlab.ui.control.Label
        SelectionIterationListBox       matlab.ui.control.ListBox
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes5_2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        p % app.ALGORITHM configuration
        d % Domain configuration
        map % Current map object
        classification % Classification
        selectedPrototypes % Keep selection saved here
        constraints % User constraints
        iter % PRODUQD iteration
        fitfun % Function pointer to current fitness function
        visDriftSimspace % Check whether to visualize user selection drift in similarity space
        DOMAIN % Domain name
        ALGORITHM % Algorithm name
        simspace % Temporarily hold similarity space
        selectionLabels % Temporarily hold (visually) selected prototypes
    end
    
    methods (Access = private)
        
        function results = setDomain(varargin)
            % Load domain
            app = varargin{1};
            app.DOMAIN = varargin{2};
            rmpath(genpath('domain')); addpath(genpath(['domain/' app.DOMAIN]));
            dof = 'default'; if nargin > 2; dof = varargin{3}; end
            app.d{app.iter} = domain(dof);
            app.loadeddomainLabel.Text = ['DOMAIN: ' app.DOMAIN];
            
            % Set features to GUI
            app.featurexaxisDropDown.Items = app.d{app.iter}.featureLabels;
            app.featureyaxisDropDown.Items = app.d{app.iter}.featureLabels;
            app.featurexaxisDropDown.Value = app.featurexaxisDropDown.Items{1};
            app.featureyaxisDropDown.Value = app.featureyaxisDropDown.Items{2};
            
            app.d{app.iter}.penaltyWeight = app.Slider.Value;            
            app.visDriftSimspace = true;
            
            % Show example solution
            app.UIAxes3.cla;
            randInd = randn(1,app.d{app.iter}.dof);
            showPhenotype(app.UIAxes3,app.d{app.iter},randInd);
            title(app.UIAxes3,['Random Fitness Example: ' num2str(app.d{app.iter}.fitfun(randInd))]);
            axis(app.UIAxes3,'equal');axis(app.UIAxes3,[-1 1 -1 1]);
          
            % Load description and show high and low fitness example if
            % available from domain.m
            app.UIAxes9.cla;
            if isfield(app.d{app.iter},'sampleHighFit')
                showPhenotype(app.UIAxes9,app.d{app.iter},app.d{app.iter}.sampleHighFit);
                title(app.UIAxes9,['High Fitness Example: ' num2str(app.d{app.iter}.fitfun(app.d{app.iter}.sampleHighFit))]);
                axis(app.UIAxes9,'equal');axis(app.UIAxes9,[-1 1 -1 1]);
            end
            
            app.UIAxes10.cla;
            if isfield(app.d{app.iter},'sampleLowFit')
                showPhenotype(app.UIAxes10,app.d{app.iter},app.d{app.iter}.sampleLowFit);            
                title(app.UIAxes10,['Low Fitness Example: ' num2str(app.d{app.iter}.fitfun(app.d{app.iter}.sampleLowFit))]);
                axis(app.UIAxes10,'equal');axis(app.UIAxes10,[-1 1 -1 1]);
            end
            
            if isfield(app.d{app.iter},'description')
                app.DescriptionTextArea.Value = app.d{app.iter}.description;
            end
            
        end
        
        function showHistory(app,handle)
            cla(handle);
            numCols = length(app.constraints);
            for i=1:numCols
                numPrototypes = length(app.constraints{i}.selectedClasses);
                placement = [];
                for j=1:numPrototypes
                    placement(j,:) = [(i-1) (j-1)]*app.d{app.iter}.spacer;
                end
                showPhenotype(handle,app.d{app.iter},app.classification{i}.protoX(app.constraints{i}.selectedClasses,:),placement);
            end
            axis(handle,'equal');
        end
        
        function results = setQD(app,ALGORITHM)
            rmpath('QD/grid'); rmpath('QD/voronoi'); addpath(['QD/' ALGORITHM]);
            app.ALGORITHM = ALGORITHM;
            % Load configuration
            if ~isempty(app.p) && isfield(app.p{app.iter},'nGens')
                nGens = app.p{app.iter}.nGens;
                app.p{app.iter} = defaultParamSet(4);
                app.p{app.iter}.nGens = nGens;
            else
                app.p{app.iter} = defaultParamSet(4);
            end
            
            app.nogenerationsEditField.Value = app.p{app.iter}.nGens;
            if strcmp(app.ALGORITHM,'grid')
                app.resolutionSlider.Limits = [5 50];
                app.resolutionSlider.Value = 10;
            elseif strcmp(app.ALGORITHM,'voronoi')
                app.resolutionSlider.Limits = [0.001 0.1];
                app.resolutionSlider.Value = 0.01;
            end
            
            app.resolutionSlider.Value = app.p{app.iter}.featureResolution(1);
        end
        
        function results = copyFigure(app,axFrom,axTo)
            if ~isempty(axFrom.Children)
                cla(axTo);
                for i=length(axFrom.Children):-1:1
                    axFrom.Children(i).Parent = axTo;
                end
            end
        end
        
        function results = showExplicitPhenotypes(app,figHandle)
                        % Show explicit phenotypes
                        %
            cla(figHandle);
            placement = [];
            if strcmp(app.archiveDropDown.Value,'voronoi')
                genes = app.map{app.iter}.genes;
                spacer = 15*app.d{app.iter}.spacer;
                placement = spacer.* ([0 1] + [1 -1].*app.map{app.iter}.features(:,app.d{app.iter}.selectedFeatures));
            elseif strcmp(app.archiveDropDown.Value,'grid')
                spacer = app.d{app.iter}.spacer;
                for i=1:length(app.map{app.iter}.edges{1})-1
                    for j=1:length(app.map{app.iter}.edges{2})-1
                        placement((j-1)*(length(app.map{app.iter}.edges{2})-1)+i,:) = [i*spacer (length(app.map{app.iter}.edges{2})-1-j)*spacer];
                    end
                end
                genes = reshape(app.map{app.iter}.genes,size(app.map{app.iter}.genes,1)*size(app.map{app.iter}.genes,2),[]);
                
            end
            showPhenotype(figHandle,app.d{app.iter},genes,placement);
            figHandle.XLabel.String = app.d{app.iter}.featureLabels(app.d{app.iter}.selectedFeatures(1));
            figHandle.YLabel.String = app.d{app.iter}.featureLabels(app.d{app.iter}.selectedFeatures(2));
            figHandle.XTick = [];
            figHandle.YTick = [];
            axis(figHandle,spacer*[app.UIAxes.XLim app.UIAxes.YLim]);
            axis(figHandle,'equal');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
            app.iter = 1;
            app.selectedPrototypes = {};
            app.constraints = {};
            app.d = {};
            app.p = {};
            app.DOMAIN = app.domainDropDown.Value; %npoly_ffd npoly dropwave shubert4
            DOF = str2num(app.DOFDropDown.Value);
            setDomain(app, app.DOMAIN, DOF);
            app.ALGORITHM = app.archiveDropDown.Value;
            setQD(app,app.ALGORITHM);
        end

        % Button pushed function: InitRestartButton
        function InitRestartButtonPushed(app, event)
            disp(['Illuminating iteration: ' int2str(app.iter)]);
            constraints = app.constraints; if length(app.constraints) >= app.iter; constraints = app.constraints(1:app.iter); end
            if app.visDriftSimspace
                app.fitfun = @(x) objective(x,app.d{app.iter}.fitfun,constraints,app.p{app.iter}.penaltyWeight,app.p{app.iter}.driftThreshold,app.UIAxes8);
            else
                app.fitfun = @(x) objective(x,app.d{app.iter}.fitfun,constraints,app.p{app.iter}.penaltyWeight,app.p{app.iter}.driftThreshold);
            end
            if isempty(app.constraints)
                sobSequence         = scramble(sobolset(app.d{app.iter}.dof,'Skip',1e3),'MatousekAffineOwen');
                sobPoint            = 1;
                initSamples         = range(app.d{app.iter}.ranges).*sobSequence(sobPoint:(sobPoint+app.p{app.iter}.numInitSamples)-1,:)+app.d{app.iter}.ranges(1);
            else
                % Get seeds
                initSamples = [];
                for it1=1:length(app.constraints)
                    initSamples = [initSamples; app.constraints{it1}.members];
                end
            end
            [fitness, values, phenotypes]   = app.fitfun(initSamples);
            
            obsMap              = createMap(app.d{app.iter}, app.p{app.iter});
            [replaced, replacement, features] = nicheCompete(initSamples, fitness, phenotypes, obsMap, app.d{app.iter}, app.p{app.iter});
            obsMap              = updateMap(replaced,replacement,obsMap,fitness,initSamples,values,features,app.p{app.iter}.extraMapValues);
            [app.map{app.iter}, ~, ~, ~, ~] = illuminate(app.fitfun,obsMap,app.p{app.iter},app.d{app.iter},app.UIAxes,app.UIAxes11,app.UIAxes11_2);
            
            % Show data
            app.showExplicitPhenotypes(app.UIAxes7);
            app.showExplicitPhenotypes(app.UIAxes13);
            app.SimilaritySpaceButton.Enable = true; % enable similarity space button
            
        end

        % Value changed function: nogenerationsEditField
        function nogenerationsEditFieldValueChanged(app, event)
            value = app.nogenerationsEditField.Value;
            app.p{app.iter}.nGens = value;
            app.nogenerationsEditField.Value = value;
        end

        % Value changed function: domainDropDown
        function domainDropDownValueChanged(app, event)
            dof = app.domainDropDown.Value;
            setDomain(app, app.domainDropDown.Value, dof);
        end

        % Value changed function: featurexaxisDropDown
        function featurexaxisDropDownValueChanged(app, event)
            value = app.featurexaxisDropDown.Value;
            col = find(strcmp(value,app.d{app.iter}.featureLabels));
            app.d{app.iter}.selectedFeatures(1) = col;
        end

        % Value changed function: featureyaxisDropDown
        function featureyaxisDropDownValueChanged(app, event)
            value = app.featureyaxisDropDown.Value;
            col = find(strcmp(value,app.d{app.iter}.featureLabels));
            app.d{app.iter}.selectedFeatures(2) = col;
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
            app.UIAxes.cla;
            disp('TODO: UPDATE MAP!! BUT KEEP ORIGINAL')
            genes = reshape(app.map{app.iter}.genes,[],size(app.map{app.iter}.genes,ndims(app.map{app.iter}.genes)));
            fitness = reshape(app.map{app.iter}.fitness,size(app.map{app.iter}.fitness,1)*size(app.map{app.iter}.fitness,2),[]);
            features = reshape(app.map{app.iter}.features,[],size(app.map{app.iter}.features,ndims(app.map{app.iter}.features)));
            
            genes = genes(all(~isnan(genes)'),:);
            fitness = fitness(~isnan(fitness));
            features = features(all(~isnan(features)'),:);
            currentMap          = createMap(app.d{app.iter}, app.p{app.iter});
            [replaced, replacement, features] = nicheCompete(genes, fitness, [], currentMap, app.d{app.iter}, app.p{app.iter},features);
            currentMap              = updateMap(replaced,replacement,currentMap,fitness,genes,[],features,[]);
            
            viewMap(currentMap,app.d{app.iter},app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);
        end

        % Button pushed function: GeneticPrototypingButton
        function GeneticPrototypingButtonPushed(app, event)
            if isempty(app.simspace)
                [app.classification{app.iter}, app.simspace] = extractClasses(app.map{app.iter}.genes,[],'kmedoids',floor(app.noprototypesSlider.Value));
            else
                app.classification{app.iter} = extractClasses(app.map{app.iter}.genes,app.simspace,'kmedoids',floor(app.noprototypesSlider.Value));
            end
            % Show classes in similarity space
            viewClasses(app.map{app.iter}.genes,app.classification{app.iter},app.UIAxes2);
            % Show prototypes
            % Row and col placement
            numRows = ceil( size(app.classification{app.iter}.protoX,1).^(2/3) );
            for i=1:size(app.classification{app.iter}.protoX,1)
                placement(i,:) = [mod(i-1,numRows) floor((i-1)/numRows)]*app.d{app.iter}.spacer;
            end
            app.UIAxes5.cla;
            showPhenotype(app.UIAxes5,app.d{app.iter},app.classification{app.iter}.protoX,placement);
            axis(app.UIAxes5, 'equal');
            app.AddSelectionButton.Enable = true;
            app.SimilaritySpaceButton.Enable = false;
            
        end

        % Value changed function: archiveDropDown
        function archiveDropDownValueChanged(app, event)
            value = app.archiveDropDown.Value;
            setQD(app,value);
        end

        % Button pushed function: AddSelectionButton
        function AddSelectionButtonPushed(app, event)
            % Save selection
            if length(app.selectedPrototypes) < app.iter
                app.selectedPrototypes(app.iter) = {find(app.selectionLabels==1)};
            else
                app.selectedPrototypes{app.iter}(end+1) = find(app.selectionLabels==1);
            end
            disp(app.selectedPrototypes(app.iter))
            
            % Create UDHM based on selected prototypes            
            if length(app.selectedPrototypes) >= app.iter && ~isempty(app.selectedPrototypes{app.iter})
                app.constraints{app.iter} = setConstraints(app.classification{app.iter},app.selectedPrototypes{app.iter},[],'class');
                app.iter = app.iter + 1;
                app.SelectionIterationListBox.Items{end+1} = int2str(app.iter);
                app.SelectionIterationListBox.Value = int2str(app.iter);
                app.consoleTextArea.Value = 'Selection model trained.';
                app.AddSelectionButton.Enable = false;
                
                % Initialize configuration for this iteration
                app.d{app.iter} = app.d{app.iter-1};
                app.p{app.iter} = app.p{app.iter-1};
                % Show selection history
                cla(app.UIAxes6);
                app.showHistory(app.UIAxes6);
                cla(app.UIAxes5_2);
                app.showHistory(app.UIAxes5_2);
            else
                app.consoleTextArea.Value = 'No prototypes selected!';
            end
           app.selectionLabels = []; 
           app.UIAxes6.Visible = true;
           app.UIAxes11_2.Visible = true;
           app.UIAxes8.Visible = true;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if length(app.classification) >= app.iter && ~isempty(app.classification(app.iter))
                %app.classification(app.iter) = [];
                app.selectedPrototypes(app.iter) = [];
                app.constraints(app.iter) = [];
                numIterations = max(1,length(app.selectedPrototypes));
                n=1:numIterations;
                app.iter = numIterations;
                app.SelectionIterationListBox.Items = string(n);
                app.SelectionIterationListBox.Value = int2str(app.iter);
                app.IlluminationIterationEditField.Value = app.iter;
                app.AddSelectionButton.Enable = true;
                
                % Revisualize
                cla(app.UIAxes6);
                app.showHistory(app.UIAxes6);
                cla(app.UIAxes5_2);
                app.showHistory(app.UIAxes5_2);
                numRows = ceil( size(app.classification{app.iter}.protoX,1).^(2/3) );
                for i=1:size(app.classification{app.iter}.protoX,1)
                    placement(i,:) = [mod(i-1,numRows) floor((i-1)/numRows)]*app.d{app.iter}.spacer;
                end
                cla(app.UIAxes5);
                showPhenotype(app.UIAxes5,app.d{app.iter},app.classification{app.iter}.protoX,placement);
                axis(app.UIAxes5, 'equal');
                cla(app.UIAxes2);   
                viewClasses(app.map{app.iter}.genes,app.classification{app.iter},app.UIAxes2);
            end
        end

        % Value changed function: SelectionIterationListBox
        function SelectionIterationListBoxValueChanged(app, event)
            value = app.SelectionIterationListBox.Value;
            app.iter = str2num(value);
            app.UIAxes.cla;
            viewMap(app.map{app.iter},app.d{app.iter},app.UIAxes);caxis(app.UIAxes,[0 1]);
            app.showExplicitPhenotypes(app.UIAxes7);
            app.showExplicitPhenotypes(app.UIAxes13);
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.d{app.iter}.penaltyWeight = value;
        end

        % Value changed function: DOFDropDown
        function DOFDropDownValueChanged(app, event)
            app.setDomain(app.domainDropDown.Value,str2num(app.DOFDropDown.Value));
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %save('savedStateGui.mat','app.p','app.d','app.map','app.classification','app.selectedPrototypes','app.constraints','app.iter','app.fitfun');
            p = app.p;
            d = app.d;
            map = app.map;
            classification = app.classification;
            selectedPrototypes = app.selectedPrototypes;
            constraints = app.constraints;
            iteration = app.iter;
            fitfun = app.fitfun;
            save('savedStateGui.mat', 'p', 'd', 'map', 'classification', 'selectedPrototypes', 'constraints', 'iteration', 'fitfun');
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            load('savedStateGui.mat','p','d','map','classification','selectedPrototypes','constraints','iteration','fitfun');
            app.p = p;
            app.d = d;
            app.map = map;
            app.classification = classification;
            app.selectedPrototypes = selectedPrototypes;
            app.constraints = constraints;
            app.iter = iteration;
            app.fitfun = fitfun;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %% Workarounds for UIAxes not having callbacks..
            pos = event.Source.CurrentPoint;
            
            %  Click on prototypes and select
            if strcmp(app.TabGroup.SelectedTab.Title,'Analysis/Selection') && strcmp(app.AddSelectionButton.Enable,'on')
                figPos = app.UIAxes5.InnerPosition;
                if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                    if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                        relPos = app.UIAxes5.CurrentPoint(1,1:2);
                        polyAxes = app.UIAxes5.Children;
                        polys = []; numVerts = 0;
                        for i=1:length(polyAxes)
                            polys = [polys;polyAxes(i).Shape.Vertices];
                            numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                        end
                        numVerts(1) = [];
                        distances = pdist2(relPos,polys);
                        [~,closest] = min(distances);
                        closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                        closestPrototypes = find(closestPrototypes);
                        closestPrototypes = closestPrototypes(end);
                        
                        if isempty(app.selectionLabels)
                            app.selectionLabels = zeros(1,size(app.classification{app.iter}.protoX,1));
                        end
                        app.selectionLabels(closestPrototypes) = mod(app.selectionLabels(closestPrototypes)+1,2);
                                               
                        % Overwrite selected prototypes with highlights
                        app.UIAxes5.cla;
                        numRows = ceil( size(app.classification{app.iter}.protoX,1).^(2/3) );
                        for i=1:size(app.classification{app.iter}.protoX,1)
                            placement(i,:) = [mod(i-1,numRows) floor((i-1)/numRows)]*app.d{app.iter}.spacer;
                        end
                        
                        showPhenotype(app.UIAxes5,app.d{app.iter},app.classification{app.iter}.protoX,placement,app.selectionLabels+1);
                        axis(app.UIAxes5, 'equal');
                    end
                end
            end
            
            %  Click on prototypes and deselect
            if strcmp(app.TabGroup.SelectedTab.Title,'Analysis/Selection') && strcmp(app.AddSelectionButton.Enable,'on') && ~isempty(app.selectedPrototypes) && length(app.selectedPrototypes) == app.iter~isempty(app.selectedPrototypes{app.iter})
                figPos = app.UIAxes5_2.InnerPosition;
                if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                    if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                        if ~isempty(app.selectedPrototypes{app.iter})
                            relPos = app.UIAxes5_2.CurrentPoint(1,1:2);
                            polyAxes = app.UIAxes5_2.Children;
                            polys = []; numVerts = 0;
                            for i=1:length(polyAxes)
                                polys = [polys;polyAxes(i).Shape.Vertices];
                                numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                            end
                            numVerts(1) = [];
                            distances = pdist2(relPos,polys);
                            [~,closest] = min(distances);
                            closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                            closestPrototypes = find(closestPrototypes);
                            app.selectedPrototypes{app.iter}(closestPrototypes(end)) = [];
                            % Row and col placement
                            cla(app.UIAxes5_2);
                            if ~isempty(app.selectedPrototypes{app.iter})
                                for i=1:size(app.classification{app.iter}.protoX(app.selectedPrototypes{app.iter},:),1)
                                    placement(i,:) = [(i-1)*app.d{app.iter}.spacer 0];
                                end
                                cla(app.UIAxes5_2);
                                showPhenotype(app.UIAxes5_2,app.d{app.iter},app.classification{app.iter}.protoX(app.selectedPrototypes{app.iter},:),placement);
                                axis(app.UIAxes5_2,'equal');
                            end
                        end
                    end
                end
            end
        end

        % Value changed function: VisualizeDriftCheckBox
        function VisualizeDriftCheckBoxValueChanged(app, event)
            value = app.VisualizeDriftCheckBox.Value;
            app.visDriftSimspace = value;
        end

        % Button pushed function: SimilaritySpaceButton
        function SimilaritySpaceButtonPushed(app, event)
            app.simspace = getSimSpace(app.map{app.iter}.genes);
            scatter(app.UIAxes2,app.simspace(:,1),app.simspace(:,2),8,'black','filled');
            app.SimilaritySpaceButton.Enable = false;            
        end

        % Value changed function: resolutionSlider
        function resolutionSliderValueChanged(app, event)
            value = app.resolutionSlider.Value;
            if strcmp(app.ALGORITHM,'grid')
                app.p{app.iter}.featureResolution = [floor(value) floor(value)];
            elseif strcmp(app.ALGORITHM,'voronoi')
                app.p{app.iter}.featureResolution = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Enable = 'off';
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Enable = 'off';
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Enable = 'off';
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DomainTab = uitab(app.TabGroup);
            app.DomainTab.Title = 'Domain';
            app.UIAxes3 = uiaxes(app.DomainTab);
            title(app.UIAxes3, 'Example Solution')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.DomainConfigurationPanel = uipanel(app.DomainTab);
            app.DomainConfigurationPanel.Title = 'Domain Configuration';
            app.domainDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.domainDropDownLabel.Text = 'domain';
            app.domainDropDown = uidropdown(app.DomainConfigurationPanel);
            app.domainDropDown.Items = {'npoly_ffd'};
            app.domainDropDown.ValueChangedFcn = createCallbackFcn(app, @domainDropDownValueChanged, true);
            app.domainDropDown.Tooltip = {'Select the domain'};
            app.domainDropDown.Value = 'npoly_ffd';
            app.loadeddomainLabel = uilabel(app.DomainConfigurationPanel);
            app.loadeddomainLabel.Text = 'loaded domain: ';
            app.DOFDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.DOFDropDownLabel.Text = 'DOF';
            app.DOFDropDown = uidropdown(app.DomainConfigurationPanel);
            app.DOFDropDown.Items = {'8', '12', '16', '20', '24', '28', '32', '36'};
            app.DOFDropDown.ValueChangedFcn = createCallbackFcn(app, @DOFDropDownValueChanged, true);
            app.DOFDropDown.Tooltip = {'Set number of degrees of freedom'};
            app.DOFDropDown.Value = '16';
            app.DescriptionTextAreaLabel = uilabel(app.DomainTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.DomainTab);
            app.DescriptionTextArea.Editable = 'off';
            app.UIAxes9 = uiaxes(app.DomainTab);
            title(app.UIAxes9, 'Example')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            app.UIAxes10 = uiaxes(app.DomainTab);
            title(app.UIAxes10, 'Low Fitness Example')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            app.IlluminationTab = uitab(app.TabGroup);
            app.IlluminationTab.Title = 'Illumination';
            app.QDConfigurationPanel = uipanel(app.IlluminationTab);
            app.QDConfigurationPanel.Title = 'QD Configuration';
            app.archiveDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.archiveDropDownLabel.Text = 'archive';
            app.archiveDropDown = uidropdown(app.QDConfigurationPanel);
            app.archiveDropDown.Items = {'grid', 'voronoi', ''};
            app.archiveDropDown.ValueChangedFcn = createCallbackFcn(app, @archiveDropDownValueChanged, true);
            app.archiveDropDown.Tooltip = {'Set QD archive type'};
            app.archiveDropDown.Value = 'grid';
            app.nogenerationsEditFieldLabel = uilabel(app.QDConfigurationPanel);
            app.nogenerationsEditFieldLabel.Text = 'no. generations';
            app.nogenerationsEditField = uieditfield(app.QDConfigurationPanel, 'numeric');
            app.nogenerationsEditField.Limits = [1 100000];
            app.nogenerationsEditField.RoundFractionalValues = 'on';
            app.nogenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @nogenerationsEditFieldValueChanged, true);
            app.nogenerationsEditField.Value = 100;
            app.featurexaxisDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.featurexaxisDropDownLabel.Text = 'feature x axis';
            app.featurexaxisDropDown = uidropdown(app.QDConfigurationPanel);
            app.featurexaxisDropDown.Items = {'default'};
            app.featurexaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featurexaxisDropDownValueChanged, true);
            app.featurexaxisDropDown.Tooltip = {'Select features'};
            app.featurexaxisDropDown.Value = 'default';
            app.featureyaxisDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.featureyaxisDropDownLabel.Text = 'feature y axis';
            app.featureyaxisDropDown = uidropdown(app.QDConfigurationPanel);
            app.featureyaxisDropDown.Items = {'default'};
            app.featureyaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featureyaxisDropDownValueChanged, true);
            app.featureyaxisDropDown.Tooltip = {'Select features'};
            app.featureyaxisDropDown.Value = 'default';
            app.surrogateassistanceCheckBox = uicheckbox(app.QDConfigurationPanel);
            app.surrogateassistanceCheckBox.Enable = 'off';
            app.surrogateassistanceCheckBox.Tooltip = {'When available you can replace most'; 'precise evaluations with a surrogate model. '; 'This will split up the Illumination '; 'phase in a training plus an illumination phase.'};
            app.surrogateassistanceCheckBox.Text = 'surrogate assistance';
            app.resolutionSliderLabel = uilabel(app.QDConfigurationPanel);
            app.resolutionSliderLabel.Text = 'resolution';
            app.resolutionSlider = uislider(app.QDConfigurationPanel);
            app.resolutionSlider.Limits = [0 10];
            app.resolutionSlider.ValueChangedFcn = createCallbackFcn(app, @resolutionSliderValueChanged, true);
            app.resolutionSlider.Tooltip = {'Set resolution. Warning: the resolution is interpreted depending on the type of archive'};
            app.resolutionSlider.Value = 2;
            app.QDcommandsPanel = uipanel(app.IlluminationTab);
            app.QDcommandsPanel.Title = 'QD commands';
            app.InitRestartButton = uibutton(app.QDcommandsPanel, 'push');
            app.InitRestartButton.ButtonPushedFcn = createCallbackFcn(app, @InitRestartButtonPushed, true);
            app.InitRestartButton.Tooltip = {'QD is started when pressing this button. '; 'The phenotypic map will be updated every n generations. '; 'After QD is done'; ' the phenotypes will be shown to the right.'};
            app.InitRestartButton.Text = 'Init/Restart';
            app.RedrawButton = uibutton(app.QDcommandsPanel, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Tooltip = {'Redraw the map without restarting QD.'; 'You can use this to analyze the map based on a different feature set.'};
            app.RedrawButton.Text = 'Redraw';
            app.UIAxes = uiaxes(app.IlluminationTab);
            title(app.UIAxes, 'Phenotypic Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes6 = uiaxes(app.IlluminationTab);
            title(app.UIAxes6, 'Selection History')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.Visible = 'off';
            app.UIAxes7 = uiaxes(app.IlluminationTab);
            title(app.UIAxes7, 'Explicit Phenotypes')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes8 = uiaxes(app.IlluminationTab);
            title(app.UIAxes8, 'View from Similarity Space')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.UIAxes8.Visible = 'off';
            app.UIAxes11 = uiaxes(app.IlluminationTab);
            title(app.UIAxes11, 'Mean Fitness')
            xlabel(app.UIAxes11, 'Generations')
            ylabel(app.UIAxes11, 'Fitness')
            app.UIAxes11_2 = uiaxes(app.IlluminationTab);
            title(app.UIAxes11_2, 'Mean User Selection Drift')
            xlabel(app.UIAxes11_2, 'Generations')
            ylabel(app.UIAxes11_2, 'Drift')
            app.UIAxes11_2.Visible = 'off';
            app.VisualizeDriftCheckBox = uicheckbox(app.IlluminationTab);
            app.VisualizeDriftCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizeDriftCheckBoxValueChanged, true);
            app.VisualizeDriftCheckBox.Text = 'Visualize Drift';
            app.VisualizeDriftCheckBox.Value = true;
            app.UIAxes12 = uiaxes(app.IlluminationTab);
            title(app.UIAxes12, 'Acquisition')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            app.UIAxes12.Visible = 'off';
            app.UserSelectionPressurePanel = uipanel(app.IlluminationTab);
            app.UserSelectionPressurePanel.Title = 'User Selection Pressure';
            app.SliderLabel = uilabel(app.UserSelectionPressurePanel);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.UserSelectionPressurePanel);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 5 10];
            app.Slider.MajorTickLabels = {'0', '5', '10'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tooltip = {'The weight used in the soft constraints. '; 'Probably you do not need to change this.'; 'However if you do'; ' you should expect an increase of similarity between '; 'produced solutions by QD and the user''s selection.'};
            app.Slider.Value = 2;
            app.ExplicitTab = uitab(app.TabGroup);
            app.ExplicitTab.Title = 'Explicit';
            app.UIAxes13 = uiaxes(app.ExplicitTab);
            title(app.UIAxes13, 'Title')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            app.AnalysisSelectionTab = uitab(app.TabGroup);
            app.AnalysisSelectionTab.Title = 'Analysis/Selection';
            app.AnalysisPanel = uipanel(app.AnalysisSelectionTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.GeneticPrototypingButton = uibutton(app.AnalysisPanel, 'push');
            app.GeneticPrototypingButton.ButtonPushedFcn = createCallbackFcn(app, @GeneticPrototypingButtonPushed, true);
            app.GeneticPrototypingButton.Text = 'Genetic Prototyping';
            app.noprototypesSliderLabel = uilabel(app.AnalysisPanel);
            app.noprototypesSliderLabel.Text = 'no. prototypes';
            app.noprototypesSlider = uislider(app.AnalysisPanel);
            app.noprototypesSlider.Limits = [1 40];
            app.noprototypesSlider.MajorTicks = [1 10 20 30 40];
            app.noprototypesSlider.MinorTicks = [5 15 25 35];
            app.noprototypesSlider.Value = 10;
            app.SimilaritySpaceButton = uibutton(app.AnalysisPanel, 'push');
            app.SimilaritySpaceButton.ButtonPushedFcn = createCallbackFcn(app, @SimilaritySpaceButtonPushed, true);
            app.SimilaritySpaceButton.Text = 'Similarity Space';
            app.UIAxes2 = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxes2, 'Similarity Space')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UserSelectionPanel = uipanel(app.AnalysisSelectionTab);
            app.UserSelectionPanel.Title = 'User Selection';
            app.AddSelectionButton = uibutton(app.UserSelectionPanel, 'push');
            app.AddSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectionButtonPushed, true);
            app.AddSelectionButton.Text = 'Add Selection';
            app.RemoveButton = uibutton(app.UserSelectionPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.consoleTextAreaLabel = uilabel(app.AnalysisSelectionTab);
            app.consoleTextAreaLabel.Enable = 'off';
            app.consoleTextAreaLabel.Text = 'console';
            app.consoleTextArea = uitextarea(app.AnalysisSelectionTab);
            app.consoleTextArea.Editable = 'off';
            app.consoleTextArea.Enable = 'off';
            app.SelectionIterationListBoxLabel = uilabel(app.AnalysisSelectionTab);
            app.SelectionIterationListBoxLabel.Text = {'Selection'; 'Iteration'};
            app.SelectionIterationListBox = uilistbox(app.AnalysisSelectionTab);
            app.SelectionIterationListBox.Items = {'1'};
            app.SelectionIterationListBox.ValueChangedFcn = createCallbackFcn(app, @SelectionIterationListBoxValueChanged, true);
            app.SelectionIterationListBox.Value = '1';
            app.UIAxes5 = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxes5, 'Prototypes')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes5.XLim = [0 2.5];
            app.UIAxes5_2 = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxes5_2, 'Selection History')
            xlabel(app.UIAxes5_2, 'Iteration')
            ylabel(app.UIAxes5_2, '')
            app.UIAxes5_2.XLim = [-1 20];
            app.UIAxes5_2.YLim = [-2 2];
            app.UIAxes5_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoGUItabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end